classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        NghimTab                matlab.ui.container.Tab
        NisuyTab                matlab.ui.container.Tab
        HiquyTab                matlab.ui.container.Tab
        ohmTab                  matlab.ui.container.Tab
        TchphnTab               matlab.ui.container.Tab
        method_input            matlab.ui.control.DropDown
        MethodLabel             matlab.ui.control.Label
        INPUTListBox            matlab.ui.control.ListBox
        INPUTListBoxLabel       matlab.ui.control.Label
        yEditField              matlab.ui.control.EditField
        YEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.EditField
        XEditFieldLabel         matlab.ui.control.Label
        Result                  matlab.ui.control.NumericEditField
        ResultLabel             matlab.ui.control.Label
        TnhButton               matlab.ui.control.Button
        N_input                 matlab.ui.control.NumericEditField
        NEditFieldLabel         matlab.ui.control.Label
        a_input                 matlab.ui.control.NumericEditField
        aLabel                  matlab.ui.control.Label
        Function_input          matlab.ui.control.EditField
        FUNCTIONEditFieldLabel  matlab.ui.control.Label
        b_input                 matlab.ui.control.NumericEditField
        bLabel                  matlab.ui.control.Label
        TCHPHNLabel             matlab.ui.control.Label
        GiithiunhmTab           matlab.ui.container.Tab
    end

    
    methods (Access = public)
        function y= tichphanhinhthang(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            y=(h/2)*(fx(a)+fx(b)+2*sum(fx(a+i*h)));
        end
        
        function y= tichphanSimpson1phan3(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            S=fx(a+i*h);
            y=(h/3)*(fx(a)+fx(b)+4*sum(S(2:2:N-1))+2*sum(S(1:2:N-1)));
        end
        
        function y= tichphanSimpson3phan8(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            S=fx(a+i*h);
            j=3:3:N-1;
            S3=sum(S(3:3:N-1));
            S(j)=[];
            S0=sum(S);
            y=(3*h/8)*(fx(a)+fx(b)+3*S0+2*S3);
        end
        function dathuc =lagrange(~,x_array,y_array)
             syms x;
             syms l [1 length(x_array)];
             for i=1:length(x_array)
                    x1=x_array;
                    x1(i)=[];
                    l(i)=prod(x-x1)/prod(x_array(i)-x1);
              end
            dathuc=sum(l.*y_array);
        end 

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhButton
        function ButtonPushed(app, event)
            a=app.a_input.Value;
            b=app.b_input.Value;
            N=app.N_input.Value;
            switch app.INPUTListBox.Value
                case 'FUNCTION'
                    temp=0;
                    f=app.Function_input.Value;
                    fx=str2func(['@(x)',f]);
                case 'x,y'
                    temp=1;
                    x=str2num(app.xEditField.Value);
                    y=str2num(app.yEditField.Value);
                    a=x(1);
                    N=length(x)-1;
                    b=x(length(x));
                    dathuc=lagrange(app,x,y);
                    syms fx(x);
                    fx(x) =simplify(dathuc);
            end
        
            switch app.method_input.Value
                case 'hình thang'
                     y=tichphanhinhthang(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'Simpson 1/3'
                    y=tichphanSimpson1phan3(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'Simpson 3/8'
                    y=tichphanSimpson3phan8(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'tính chính xác'
                    if temp==0
                    app.Result.Value=integral(fx,a,b);
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.TCHPHNLabel = uilabel(app.TchphnTab);
            app.TCHPHNLabel.Text = 'TÍCH PHÂN';
            app.bLabel = uilabel(app.TchphnTab);
            app.bLabel.Text = 'b';
            app.b_input = uieditfield(app.TchphnTab, 'numeric');
            app.FUNCTIONEditFieldLabel = uilabel(app.TchphnTab);
            app.FUNCTIONEditFieldLabel.Text = 'FUNCTION';
            app.Function_input = uieditfield(app.TchphnTab, 'text');
            app.aLabel = uilabel(app.TchphnTab);
            app.aLabel.Text = 'a';
            app.a_input = uieditfield(app.TchphnTab, 'numeric');
            app.NEditFieldLabel = uilabel(app.TchphnTab);
            app.NEditFieldLabel.Text = 'N';
            app.N_input = uieditfield(app.TchphnTab, 'numeric');
            app.TnhButton = uibutton(app.TchphnTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.ResultLabel = uilabel(app.TchphnTab);
            app.ResultLabel.Text = 'Result';
            app.Result = uieditfield(app.TchphnTab, 'numeric');
            app.XEditFieldLabel = uilabel(app.TchphnTab);
            app.XEditFieldLabel.Text = 'X';
            app.xEditField = uieditfield(app.TchphnTab, 'text');
            app.YEditFieldLabel = uilabel(app.TchphnTab);
            app.YEditFieldLabel.Text = 'Y';
            app.yEditField = uieditfield(app.TchphnTab, 'text');
            app.INPUTListBoxLabel = uilabel(app.TchphnTab);
            app.INPUTListBoxLabel.Text = 'INPUT';
            app.INPUTListBox = uilistbox(app.TchphnTab);
            app.INPUTListBox.Items = {'FUNCTION', 'x,y'};
            app.INPUTListBox.Value = 'FUNCTION';
            app.MethodLabel = uilabel(app.TchphnTab);
            app.MethodLabel.Text = 'Method';
            app.method_input = uidropdown(app.TchphnTab);
            app.method_input.Items = {'hình thang', 'Simpson 1/3', 'Simpson 3/8', 'tính chính xác'};
            app.method_input.Value = 'hình thang';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end